classdef KACGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AssociatedetectionsonSoundfilesLabel_2  matlab.ui.control.Label
        MinCorrelationScoreEditField    matlab.ui.control.NumericEditField
        MinCorrelationScoreEditFieldLabel  matlab.ui.control.Label
        WindowLengthEditField           matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel      matlab.ui.control.Label
        WindowNameDropDown              matlab.ui.control.DropDown
        WindowNameDropDownLabel         matlab.ui.control.Label
        NoisePadLengthEditField         matlab.ui.control.NumericEditField
        NoisePadLengthEditFieldLabel    matlab.ui.control.Label
        ReferenceChannelDropDown        matlab.ui.control.DropDown
        ReferenceChannelDropDownLabel   matlab.ui.control.Label
        HydrophoneLocCSVEditField       matlab.ui.control.EditField
        HydrophoneLocCSVEditFieldLabel  matlab.ui.control.Label
        AssociateButton                 matlab.ui.control.Button
        NormalizationDirectionDropDown  matlab.ui.control.DropDown
        NormalizationDirectionDropDownLabel  matlab.ui.control.Label
        RavenSelectionTableEditField    matlab.ui.control.EditField
        RavenSelectionTableEditFieldLabel  matlab.ui.control.Label
        AssociatedetectionsonSoundfilesLabel  matlab.ui.control.Label
        SoundFileLocationEditField      matlab.ui.control.EditField
        SoundFileLocationEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)

    end
    
    properties (Access = public)
        file_list;    % Sound file list
        fs; % sample rate
        hyd_loc; % hydrophone lat, lon, depth
        detectionsLocation; % raven selection table location
        detections; % table of contatenated detections file
        hydLoc; % lat, lon, depth channel
        Nchannels; % number of channelse in the data
        ref_chan
        noisePadLength
        winname
        winLen
        dex;
        delays;
        crossScores
        corThresh
    end
    
    methods (Access = private)
        
        function detectionsOut = getDetections(app)
            
            detectionsLoc = app.RavenSelectionTableEditField.Value;
            
            NNoutput = dir(fullfile(detectionsLoc, '*.txt'))
            detectionsOut=table();
            for ii = 1:length(NNoutput)
                
                   D = regexp(NNoutput(ii).name,'\d{8}','match','once');
                   tstamp = datenum(D,'yyyymmdd');

                fname = fullfile(NNoutput(ii).folder, NNoutput(ii).name)
                detections = readtable(fname, 'ReadVariableNames',true,'Delimiter','\t');                detections.streamSampleStart = round(detections.BeginTime_s_*app.fs)+(24*(ii-1)*60*60*app.fs)+1;
                detections.streamSampleStop = detections.streamSampleStart+(app.fs*2);
                detections = sortrows(detections,'streamSampleStart','ascend');
                detections.date = ones([height(detections), 1])*...
                   tstamp;
                detections.BeginTime_s_= detections.BeginTime_s_+((ii-1)*24*60*60);
                detections.EndTime_s_= detections.EndTime_s_+((ii-1)*24*60*60);
                
                chanCall=ones(1,app.Nchannels);
                
                
                detectionsOut = [detectionsOut; detections];
                
                
            end
            
            detectionsOut = sortrows(detectionsOut,'streamSampleStart','ascend');
            % %
            %   detectionsOut(detectionsOut.Score<0.7,:)=[];
            %


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SoundFileLocationEditField
        function SoundFileLocationEditFieldValueChanged(app, event)
            value = app.SoundFileLocationEditField.Value;
%             try
%                 file_list=(  dir(fullfile(value, '*.aif')));
%             catch me
%                 try file_list=(  dir(fullfile(value, '*.wav')));
%                     
%                 catch ME
%                     file_list=(  dir(fullfile(value, '*.flac')));
%                     
%                 end
%             end
            file_list=[dir(fullfile(value,'*.aiff'));...
                dir(fullfile(value,'*.flac'));...
                dir(fullfile(value, '*.wav'))]
            
          
            file_listTable = struct2table(file_list)

            
            file_listTable.MatlabDate =cellfun(@(x) datenum(regexp(x,'\d{8}_\d{6}','match','once'), 'yyyymmdd_HHMMSS'),...
                file_listTable.name)
                    
            % laod the first file to get the reqired bits
            soundInfio =audioinfo(fullfile(file_listTable.folder{1}, file_listTable.name{1}))
            app.fs = soundInfio.SampleRate;
            
            % Samples into the entire stream
            file_listTable.SampleStrt = app.fs*60*60*24*(file_listTable.MatlabDate-floor(min(file_listTable.MatlabDate)));
            
            % Seconds inot the s
            file_listTable.SecStart = (file_listTable.MatlabDate-floor(file_listTable.MatlabDate))*60*60*24;
            file_listTable.SampStop =file_listTable.SampleStrt+(15*60*app.fs);
            
            app.Nchannels = soundInfio.NumChannels;
            
            app.file_list= file_listTable;
            aa = cellfun(@(x) num2str(x), {1:  app.Nchannels}, 'UniformOutput', false)
            app.ReferenceChannelDropDown.Items=split(aa{1});
            
            
        end

        % Value changed function: RavenSelectionTableEditField
        function RavenSelectionTableEditFieldValueChanged(app, event)
            value = app.RavenSelectionTableEditField.Value;
           
        end

        % Value changing function: HydrophoneLocCSVEditField
        function HydrophoneLocCSVEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: HydrophoneLocCSVEditField
        function HydrophoneLocCSVEditFieldValueChanged(app, event)
            value = readtable(app.HydrophoneLocCSVEditField.Value);
            
            % set the allowable reference channels based on max channels
            %app.ReferenceChannelDropDown.Items = unique(hydLoc.Channel)
            
        end

        % Button pushed function: AssociateButton
        function AssociateButtonPushed(app, event)
            
            
            noisePadLen = app.NoisePadLengthEditField.Value;
            SoundFileLocationEditFieldValueChanged(app, event);
            
            % if it hasn't run yet, get the list of soundfiles
            if isempty(app.file_list)
                SoundFileLocationEditFieldValueChanged(app, event)
            end
            
            file_listTable = app.file_list;
            
            % get the detections
            detections= getDetections(app);
            
            app.hydLoc = readtable(app.HydrophoneLocCSVEditField.Value);
            ref_chan = str2num(app.ReferenceChannelDropDown.Value);
            
            % Max delays between hydrophones
            %get the x corr times between hydrophone 10 (primary) and others
            dist=[];
            for ii=1:app.Nchannels
                % distance between the primary and secondary
                dist(ii) = vdist2(...
                    app.hydLoc.Lat(ref_chan),...
                    app.hydLoc.Lon(ref_chan),...
                    app.hydLoc.Lat(ii),...
                    app.hydLoc.Lon(ii));
            end
            
            hydDelay = dist/1500;
            % convert delays from seconds to samples
            hydDelay = round(hydDelay * app.fs);
            % get max delay
            max_delay = max(hydDelay);
%             
%             % Because I can't be arsed to properly fix it right now,remove
%             % detections that are at the beginning or end of the filestream
%             % such that the noise buffer doesn't teweak out
% 
%             detections = detections(...
%                 detections.BeginTime_s_>(app.NoisePadLengthEditField.Value+5) &...
%                 detections.EndTime_s_<(max(file_listTable.SampStop)/app.fs-(app.NoisePadLengthEditField.Value+5)),:);
            
            % pre allocate
            delays = nan(sum(detections.Channel==ref_chan), app.Nchannels);
            
            % call indicies
            dex = delays;
            
            % cross correlation scores
            crossScores = dex;
            % arrival times
            arrivalArray = dex;
            
            % only the reference detections
            detectionsRef =detections(detections.Channel==ref_chan,:);
            
            
            % Primary detections
            for ii =1:height(detectionsRef)
                
                
                [delays(ii,:), dex(ii,:), crossScores(ii,:),val] = ...
                    createAssociations(app,detectionsRef, detections, ii, file_listTable,...
                    hydDelay);
                
                if ~isempty(val)
                    valueout(ii)=val;
                end
                dex(ii,ref_chan)=detectionsRef.Selection(ii);
                
                ii
                
                
            end
            app.dex = dex;
            app.delays = delays;
            app.crossScores= crossScores;
            
            
            disp('done')
            
            % Trim the correlation scores
            app.dex(crossScores< app.MinCorrelationScoreEditField.Value) = 0/0;
            app.delays(crossScores< app.MinCorrelationScoreEditField.Value) = 0/0;
            app.dex(:,str2num(app.ReferenceChannelDropDown.Value))=[];
            
            
            detectionsRef.Associates = repmat({'aa'}, height(detectionsRef),1);
            
            % add column for associates
            for ii =1:height(detectionsRef)
                detectionsRef.Associates(ii)=...
                    {num2str(app.dex(ii,~isnan(app.dex(ii,:))))};
            end
            
            % Create the raven Table
            ravenTable = table(detectionsRef.Selection,...
                detectionsRef.View,...
                detectionsRef.Channel,...
                detectionsRef.BeginTime_s_,...
                detectionsRef.EndTime_s_, detectionsRef.LowFreq_Hz_,...
                detectionsRef.HighFreq_Hz_, detectionsRef.Associates);
            
            header = {'Selection', 'View', 'Channel',...
                'Begin Time (s)', 'End Time (s)',...
                'Low Freq (Hz)', 'High Freq (Hz)', 'Associations'};
            Tcell = [header; table2cell(ravenTable)];
            writecell(Tcell,...
            ['Ch_', num2str(app.ReferenceChannelDropDown.Value),...
            '_FileAssociates.txt'],...
                'Delimiter', '\t')
            
            
        end

        % Value changed function: ReferenceChannelDropDown
        function ReferenceChannelDropDownValueChanged(app, event)
            value = app.ReferenceChannelDropDown.Value;
            app.ref_chan = str2num(value);
        end

        % Value changed function: WindowNameDropDown
        function WindowNameDropDownValueChanged(app, event)
           value = app.WindowNameDropDown.Value;
            app.winname = value;
            
        end

        % Value changed function: WindowLengthEditField
        function WindowLengthEditFieldValueChanged(app, event)
            value = app.WindowLengthEditField.Value;
            app.winLen = value;
        end

        % Value changed function: MinCorrelationScoreEditField
        function MinCorrelationScoreEditFieldValueChanged(app, event)
            value = app.MinCorrelationScoreEditField.Value;
            app.corThresh
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SoundFileLocationEditFieldLabel = uilabel(app.UIFigure);
            app.SoundFileLocationEditFieldLabel.Text = 'Sound File Location';
            app.SoundFileLocationEditField = uieditfield(app.UIFigure, 'text');
            app.SoundFileLocationEditField.ValueChangedFcn = createCallbackFcn(app, @SoundFileLocationEditFieldValueChanged, true);
            app.SoundFileLocationEditField.Tooltip = {'folder containing continuous soundfiles'};
            app.SoundFileLocationEditField.Value = 'G:\AS-Filer\BIO\sparks\Students\General\AnkeK\Orsted\ORSTED_Localization\DetectionAssociation-main\CCB_2019_Data\20190220';
            app.AssociatedetectionsonSoundfilesLabel = uilabel(app.UIFigure);
            app.AssociatedetectionsonSoundfilesLabel.Text = 'Associate detections on Soundfiles';
            app.RavenSelectionTableEditFieldLabel = uilabel(app.UIFigure);
            app.RavenSelectionTableEditFieldLabel.Text = 'Raven Selection Table';
            app.RavenSelectionTableEditField = uieditfield(app.UIFigure, 'text');
            app.RavenSelectionTableEditField.ValueChangedFcn = createCallbackFcn(app, @RavenSelectionTableEditFieldValueChanged, true);
            app.RavenSelectionTableEditField.Tooltip = {'folder containing Raven selection tables'};
            app.RavenSelectionTableEditField.Value = 'G:\AS-Filer\BIO\sparks\Students\General\AnkeK\Orsted\ORSTED_Localization\DetectionAssociation-main\Selection Tables';
            app.NormalizationDirectionDropDownLabel = uilabel(app.UIFigure);
            app.NormalizationDirectionDropDownLabel.Text = 'Normalization Direction';
            app.NormalizationDirectionDropDown = uidropdown(app.UIFigure);
            app.NormalizationDirectionDropDown.Items = {'matrix', 'column', 'row'};
            app.NormalizationDirectionDropDown.Value = 'row';
            app.AssociateButton = uibutton(app.UIFigure, 'push');
            app.AssociateButton.ButtonPushedFcn = createCallbackFcn(app, @AssociateButtonPushed, true);
            app.AssociateButton.Text = 'Associate!';
            app.HydrophoneLocCSVEditFieldLabel = uilabel(app.UIFigure);
            app.HydrophoneLocCSVEditFieldLabel.Text = 'Hydrophone Loc CSV';
            app.HydrophoneLocCSVEditField = uieditfield(app.UIFigure, 'text');
            app.HydrophoneLocCSVEditField.ValueChangedFcn = createCallbackFcn(app, @HydrophoneLocCSVEditFieldValueChanged, true);
            app.HydrophoneLocCSVEditField.ValueChangingFcn = createCallbackFcn(app, @HydrophoneLocCSVEditFieldValueChanging, true);
            app.HydrophoneLocCSVEditField.Tooltip = {'CSV with hydrophone lat, lon, and depth in decimal degrees '};
            app.ReferenceChannelDropDownLabel = uilabel(app.UIFigure);
            app.ReferenceChannelDropDownLabel.Text = 'Reference Channel';
            app.ReferenceChannelDropDown = uidropdown(app.UIFigure);
            app.ReferenceChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ReferenceChannelDropDownValueChanged, true);
            app.NoisePadLengthEditFieldLabel = uilabel(app.UIFigure);
            app.NoisePadLengthEditFieldLabel.Text = 'Noise Pad Length';
            app.NoisePadLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoisePadLengthEditField.Value = 10;
            app.WindowNameDropDownLabel = uilabel(app.UIFigure);
            app.WindowNameDropDownLabel.Text = 'Window Name';
            app.WindowNameDropDown = uidropdown(app.UIFigure);
            app.WindowNameDropDown.Items = {'Hann', 'Hamming', 'Blackman', 'None'};
            app.WindowNameDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowNameDropDownValueChanged, true);
            app.WindowNameDropDown.Value = 'Hann';
            app.WindowLengthEditFieldLabel = uilabel(app.UIFigure);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowLengthEditField.ValueChangedFcn = createCallbackFcn(app, @WindowLengthEditFieldValueChanged, true);
            app.WindowLengthEditField.Value = 512;
            app.MinCorrelationScoreEditFieldLabel = uilabel(app.UIFigure);
            app.MinCorrelationScoreEditFieldLabel.Text = 'Min Correlation Score';
            app.MinCorrelationScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinCorrelationScoreEditField.ValueChangedFcn = createCallbackFcn(app, @MinCorrelationScoreEditFieldValueChanged, true);
            app.MinCorrelationScoreEditField.Value = 0.5;
            app.AssociatedetectionsonSoundfilesLabel_2 = uilabel(app.UIFigure);
            app.AssociatedetectionsonSoundfilesLabel_2.Text = 'Associate detections on Soundfiles';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KACGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end